classdef App_mehran13990414 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        PlotSectionButton              matlab.ui.control.Button
        PredictionSectionButton        matlab.ui.control.Button
        OptimizationSectionButton      matlab.ui.control.Button
        NeuralNetworkSectionButton     matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        VibrationIsolationAppLabel     matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PlotTab                        matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        DrawDiagrambasedonEfficiencyoftheTrenchLabel  matlab.ui.control.Label
        PlottingExplanation            matlab.ui.control.Button
        SelectParameterstoDrawDiagramLabel  matlab.ui.control.Label
        PlottingExplanation_2          matlab.ui.control.Button
        Change1DropDownLabel           matlab.ui.control.Label
        Change1DropDown                matlab.ui.control.DropDown
        Change2DropDownLabel           matlab.ui.control.Label
        Change2DropDown                matlab.ui.control.DropDown
        SelectValueforFixParametersLabel  matlab.ui.control.Label
        PlottingExplanation_3          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        PlottingExplanation_4          matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        DropDown_2                     matlab.ui.control.DropDown
        DropDown_3                     matlab.ui.control.DropDown
        DropDown_4                     matlab.ui.control.DropDown
        FirstParDropDownLabel          matlab.ui.control.Label
        FirstParDropDown               matlab.ui.control.DropDown
        SecParDropDownLabel            matlab.ui.control.Label
        SecParDropDown                 matlab.ui.control.DropDown
        ThirdParDropDownLabel          matlab.ui.control.Label
        ThirdParDropDown               matlab.ui.control.DropDown
        FourthParDropDownLabel         matlab.ui.control.Label
        FourthParDropDown              matlab.ui.control.DropDown
        BackHomeButton                 matlab.ui.control.Button
        MNaghizadehrokniMZieglerLabel  matlab.ui.control.Label
        PredictionTab                  matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        DrawDiagrambasedonEfficiencyoftheTrenchLabel_2  matlab.ui.control.Label
        PredictionExplanation          matlab.ui.control.Button
        DataSourceDropDownLabel        matlab.ui.control.Label
        DataSourceDropDown             matlab.ui.control.DropDown
        WEditField_2Label              matlab.ui.control.Label
        WEditField                     matlab.ui.control.NumericEditField
        LengthEditField_2Label         matlab.ui.control.Label
        LengthEditField                matlab.ui.control.NumericEditField
        EPSEditField_2Label            matlab.ui.control.Label
        EPSEditField                   matlab.ui.control.NumericEditField
        VSEditField_2Label             matlab.ui.control.Label
        VSEditField                    matlab.ui.control.NumericEditField
        XlocEditField_2Label           matlab.ui.control.Label
        XlocEditField                  matlab.ui.control.NumericEditField
        DepthEditField_2Label          matlab.ui.control.Label
        DepthEditField                 matlab.ui.control.NumericEditField
        UploadDataButton               matlab.ui.control.Button
        PredictionExplanation_2        matlab.ui.control.Button
        PredictionExplanation_3        matlab.ui.control.Button
        PredictionExplanation_4        matlab.ui.control.Button
        PredictionExplanation_5        matlab.ui.control.Button
        PredictionExplanation_6        matlab.ui.control.Button
        PredictionExplanation_7        matlab.ui.control.Button
        ReadFromFileLabel              matlab.ui.control.Label
        PredictionExplanation_8        matlab.ui.control.Button
        CostumeDataLabel               matlab.ui.control.Label
        CostumDataPrediction           matlab.ui.control.Button
        Image_2                        matlab.ui.control.Image
        PredictButton                  matlab.ui.control.Button
        PredictionExplanation_9        matlab.ui.control.Button
        PredictionExplanation_10       matlab.ui.control.Button
        ThefirstpredictionwilltakesometimeLabel  matlab.ui.control.Label
        BackHomeButton_2               matlab.ui.control.Button
        MNaghizadehrokniMZieglerLabel_2  matlab.ui.control.Label
        ANNOutputEditFieldLabel        matlab.ui.control.Label
        ANNOutputEditField             matlab.ui.control.NumericEditField
        EquationOutputEditFieldLabel   matlab.ui.control.Label
        EquationOutputEditField        matlab.ui.control.NumericEditField
        OptimizationTab                matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        MinLabel                       matlab.ui.control.Label
        MaxLabel                       matlab.ui.control.Label
        GAOutputLabel                  matlab.ui.control.Label
        DrawDiagrambasedonEfficiencyoftheTrenchLabel_3  matlab.ui.control.Label
        OptimizationExplanation        matlab.ui.control.Button
        OptimizationExplanation_2      matlab.ui.control.Button
        GA_CON_CURVE                   matlab.ui.control.UIAxes
        WEditFieldLabel                matlab.ui.control.Label
        WEditField_2                   matlab.ui.control.NumericEditField
        LengthEditFieldLabel           matlab.ui.control.Label
        LengthEditField_2              matlab.ui.control.NumericEditField
        EPSEditFieldLabel              matlab.ui.control.Label
        EPSEditField_2                 matlab.ui.control.NumericEditField
        VSEditFieldLabel               matlab.ui.control.Label
        VSEditField_2                  matlab.ui.control.NumericEditField
        RunGeneticAlgorithmButton      matlab.ui.control.Button
        ArValueEditField               matlab.ui.control.NumericEditField
        XlocEditFieldLabel             matlab.ui.control.Label
        XlocEditField_2                matlab.ui.control.NumericEditField
        DepthEditFieldLabel            matlab.ui.control.Label
        DepthEditField_2               matlab.ui.control.NumericEditField
        Xloc1EditField                 matlab.ui.control.NumericEditField
        Depth1EditField                matlab.ui.control.NumericEditField
        W1EditField                    matlab.ui.control.NumericEditField
        Length1EditField               matlab.ui.control.NumericEditField
        EPS1EditField                  matlab.ui.control.NumericEditField
        VS1EditField                   matlab.ui.control.NumericEditField
        Xloc_Out_EditField             matlab.ui.control.NumericEditField
        Depth_Out_EditField            matlab.ui.control.NumericEditField
        W_Out_EditField                matlab.ui.control.NumericEditField
        Length_Out_EditField           matlab.ui.control.NumericEditField
        EPS_Out_EditField              matlab.ui.control.NumericEditField
        VS_Out_EditField               matlab.ui.control.NumericEditField
        ArValueEditFieldLabel          matlab.ui.control.Label
        BackHomeButton_3               matlab.ui.control.Button
        MNaghizadehrokniMZieglerLabel_3  matlab.ui.control.Label
        NeuralNetworkTab               matlab.ui.container.Tab
        GridLayout5                    matlab.ui.container.GridLayout
        TrainANNButton                 matlab.ui.control.Button
        ANNUploadDataButton            matlab.ui.control.Button
        DrawDiagrambasedonEfficiencyoftheTrenchLabel_4  matlab.ui.control.Label
        ANNmodelExplanation            matlab.ui.control.Button
        ANNmodelExplanation_2          matlab.ui.control.Button
        ANNmodelExplanation_3          matlab.ui.control.Button
        ANNmodelExplanation_4          matlab.ui.control.Button
        ResultofTrainedANNModelLabel   matlab.ui.control.Label
        ANNmodelExplanation_5          matlab.ui.control.Button
        ANNRES                         matlab.ui.control.UIAxes
        TrainMSEEditFieldLabel         matlab.ui.control.Label
        TrainMSEEditField              matlab.ui.control.NumericEditField
        TrainRMSEEditFieldLabel        matlab.ui.control.Label
        TrainRMSEEditField             matlab.ui.control.NumericEditField
        TrainR2EditFieldLabel          matlab.ui.control.Label
        TrainR2EditField               matlab.ui.control.NumericEditField
        TestMSEEditFieldLabel          matlab.ui.control.Label
        TestMSEEditField               matlab.ui.control.NumericEditField
        TestRMSELabel                  matlab.ui.control.Label
        TestRMSEEditField              matlab.ui.control.NumericEditField
        TestR2EditFieldLabel           matlab.ui.control.Label
        TestR2EditField                matlab.ui.control.NumericEditField
        PlotTrainButton                matlab.ui.control.Button
        PlotTestButton                 matlab.ui.control.Button
        Tr_PltDropDown                 matlab.ui.control.DropDown
        Te_pltDropDown                 matlab.ui.control.DropDown
        R2HelpBTN                      matlab.ui.control.Button
        BackHomeButton_4               matlab.ui.control.Button
        MNaghizadehrokniMZieglerLabel_4  matlab.ui.control.Label
        NumberofHiddenLayersDropDownLabel  matlab.ui.control.Label
        NumberofHiddenLayersDropDown   matlab.ui.control.DropDown
        thLayerNeronsEditFieldLabel    matlab.ui.control.Label
        thLayerNeronsEditField         matlab.ui.control.NumericEditField
        thLayerNeronsEditField_2Label  matlab.ui.control.Label
        thLayerNeronsEditField_2       matlab.ui.control.NumericEditField
        thLayerNeronsEditField_3Label  matlab.ui.control.Label
        thLayerNeronsEditField_3       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        AllData % Description
        PlotCollection={'Location','Depth','Width','Length','Eps','Vs'};
        Selected=zeros(1,6); % Description
        Ann_MODEL % Description
        User_prediction_Data
        User_Prediction_Path
        User_Ann_Data
        User_Ann_Path
        TrainData
        TrainTarget
        TestData
        TestTarget
        TestPredicted
        TrainPredicted
        TrainResult
        TestResult
    end
    
    methods (Access = private)
        

        function My_SetValue_Param(~,In,value)
%             disp(value)
            switch (value)
                case 'Location'
                   In.Items = {'3','6','9','12','15'};  
                case 'Depth'
                   In.Items = {'2','3','4','5','6','7'};  
                case 'Width'
                   In.Items = {'0.3','0.5','0.7','1','1.5'};
                case 'Length'
                   In.Items = {'2','4','6','8','10'};
                case 'Eps'
                   In.Items = {'12','15','19','22','29'};
                case 'Vs'
                   In.Items = {'200','250','300','350','400'};
            end
        end
        function results = My_GetIndLo1(~,In)
          Refrence={'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
          A = get(In);
          FirstParam=A.Value;
          for i=1:length(Refrence)
          if strcmp(FirstParam,Refrence{i})
              results=i;
              break;
          end
          end
        end
        function [A1,A2,A3,A4] = My_GetValue(app,Data,IndLo1, IndLo2, IndLo3, IndLo4)
            
                Vector=Data(:,3);
                Unq= unique(Vector);
            
            if IndLo1==3
                I1=My_GetInd3(app,app.DropDown);
                A1 = Unq(I1);
            else
               A1= str2double((app.DropDown.Value));
            end
            
            if IndLo2==3
                I1=My_GetInd3(app,app.DropDown_2);
                A2=Unq(I1);
            else
                A2=str2double((app.DropDown_2.Value));
            end
            
            if IndLo3==3
               I1=My_GetInd3(app,app.DropDown_3);
                A3=Unq(I1); 
            else
               A3=str2double((app.DropDown_3.Value)); 
            end
            
            if IndLo4==3
                I1=My_GetInd3(app,app.DropDown_4);
                A4=Unq(I1); 
            else
                A4=  str2double((app.DropDown_4.Value));
            end
        end
        function MY_PlotFunction(app,Data,FinalInd,Constants,I1,I2,ConstFe)
                    V1=Data(FinalInd,I1);
                    V2=Data(FinalInd,I2);
                    
                    Target=Data(FinalInd,7);
                    Un=unique(V2);
                    FeatureName={'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
                    Color={'magenta','blue','green','black','red','yellow','cyan'};
                    Marker={'o','*','square','x','diamond','+','Hexagram'};
                    hold(app.UIAxes,'off')
                    for i=1:length(Un)
                        A=find(Un(i)==V2);
                        B=V1(A);
                        Value=Target(A);
%                         disp([B Value])
                        plot(app.UIAxes,B, Value, 'Marker',Marker{i}, 'MarkerSize',5, 'MarkerFaceColor', Color{i}, 'LineWidth', 1, 'color', Color{i});
                        hold(app.UIAxes,'on')
                        grid(app.UIAxes,'on');
                        StrL=strcat(FeatureName{I2},'=',num2str(Un));
                        legend(app.UIAxes,StrL,'FontSize',9, 'FontName','Times New Roman', 'Interpreter','LaTex','Location','best','color','none')
                    end
                    c={'  ,   '};
                    Str=strcat(FeatureName{ConstFe(1)},'=',num2str(Constants(1)),...
                        c,FeatureName{ConstFe(2)},'=',num2str(Constants(2)),...
                        c,FeatureName{ConstFe(3)},'=',num2str(Constants(3)),...
                        c,FeatureName{ConstFe(4)},'=',num2str(Constants(4)));
                    title(app.UIAxes,Str, 'FontName','Times New Roman', 'Interpreter','LaTex');
                    xlabel(app.UIAxes,FeatureName{I1}, 'FontSize',11, 'FontName','Times New Roman', 'Interpreter','LaTex');
                    %xlabel(app.UIAxes,Str);                   
                    ylabel(app.UIAxes,'Efficiency (%)','FontSize',11, 'FontName','Times New Roman', 'Interpreter','LaTex')
%                     legend(app.UIAxes,Str,'FontSize',9, 'FontName','Times New Roman', 'Interpreter','LaTex','Location','best','color','none');
                    FileName=strcat(Str{1},'.png');

                
        end
        function results = My_GetInd3(app,In)
          Refrence= {'0.3','0.5','0.7','1','1.5'};
          A = get(In);
          FirstParam=A.Value;
          for i=1:length(Refrence)
          if strcmp(FirstParam,Refrence{i})
              results=i;
              break;
          end
          end
        end
        function  Automatic_Plot(app)
%             clc
            app.PlotButton.BackgroundColor='g';
            app.PlotButton.Enable=0;
            app.PlotCollection={'Location','Depth','Width','Length','Eps','Vs'};
            app.Selected=zeros(1,6);
              app.FirstParDropDown.Enable=0;
              app.SecParDropDown.Enable=0;
              app.ThirdParDropDown.Enable=0;
              app.FourthParDropDown.Enable=0;
              app.Change1DropDown.Enable=0;
              app.Change2DropDown.Enable=0;
            
            IndLo1=My_GetIndLo1(app,app.FirstParDropDown);
            IndLo2=My_GetIndLo1(app,app.SecParDropDown);
            IndLo3=My_GetIndLo1(app,app.ThirdParDropDown);
            IndLo4=My_GetIndLo1(app,app.FourthParDropDown);
            
            Cs1=My_GetIndLo1(app,app.Change1DropDown);
            Cs2=My_GetIndLo1(app,app.Change2DropDown);
            
                Color=[0.96,0.96,0.96];
                app.FirstParDropDown.BackgroundColor =Color;
                app.SecParDropDown.BackgroundColor   =Color;
                app.ThirdParDropDown.BackgroundColor =Color;
                app.FourthParDropDown.BackgroundColor=Color;
                
                app.Change1DropDown.BackgroundColor=Color;
                app.Change2DropDown.BackgroundColor=Color;
            
            Values=[IndLo1 IndLo2 IndLo3 IndLo4 Cs1 Cs2];
            
           % app.AllData=Data;
               Data=app.AllData;
               %%
               Label=Data(:,7);
               Zero=0==Label;
               Data(Zero,:)=[];
               %%
               [A11,A22,A33,A44]=My_GetValue(app,Data,IndLo1, IndLo2, IndLo3, IndLo4);
               %%
              

               Constants=[A11 A22 A33 A44];
%                 disp(Constants)
                Idx1=find(A11==Data(:,IndLo1));
                Idx2=find(A22==Data(:,IndLo2));
                Idx3=find(A33==Data(:,IndLo3));
                Idx4=find(A44==Data(:,IndLo4));

                Com1=intersect(Idx1,Idx2);
          
                Com2=intersect(Com1,Idx3);
     
                FinalInd=intersect(Com2,Idx4);
                ConstFe=[IndLo1 IndLo2 IndLo3 IndLo4];
                MY_PlotFunction(app,Data,FinalInd,Constants,Cs1,Cs2,ConstFe)
                app.PlotButton.BackgroundColor=Color;
           %% Reset Value (new change)
           app.PlotCollection={'Location','Depth','Width','Length','Eps','Vs'};
           app.Selected=zeros(1,6);
           
             app.FirstParDropDown.Enable=1;
             app.SecParDropDown.Enable=1;
             app.ThirdParDropDown.Enable=1;
             app.FourthParDropDown.Enable=1;
             app.Change1DropDown.Enable=1;
             app.Change2DropDown.Enable=1;
                  
           app.FirstParDropDown.Items={'Location','Depth','Width','Length','Eps','Vs'};
           app.SecParDropDown.Items={'Depth','Location','Width','Length','Eps','Vs'};
           app.ThirdParDropDown.Items={'Eps','Location','Depth','Length','Width','Vs'};
           app.FourthParDropDown.Items={'Length','Location','Depth','Width','Eps','Vs'};
           
           app.Change1DropDown.Items={'Width','Location','Depth','Length','Eps','Vs'};
           app.Change2DropDown.Items={'Vs','Location','Depth','Width','Length','Eps'};
              
                 P1=app.FirstParDropDown.Value;
                 P2=app.SecParDropDown.Value;
                 P3=app.ThirdParDropDown.Value;
                 P4=app.FourthParDropDown.Value;
                 P5=app.Change1DropDown.Value;
                 P6=app.Change2DropDown.Value;
 
             app.FirstParDropDown.Value=P1;
             app.SecParDropDown.Value=P2;
             app.ThirdParDropDown.Value=P3;
             app.FourthParDropDown.Value=P4;
             app.Change1DropDown.Value=P5;
             app.Change2DropDown.Value=P6;
%         Location
%         Depth
%         Width
%         Length
%         Eps
%         Vs     
            app.PlotButton.BackgroundColor='g';
            app.PlotButton.Enable=1;
        end
        function Out=Formola_Function(~,Data)
            A=Data(1); B=Data(2);
            C=Data(3); D=Data(4);
            E=Data(5); F=Data(6);
         Out=1165.65 + -135.508 * A + -199.311 * B + -818.815 * C + -202.128 * D + ...
    0.50278 * E + -12.8044 * F + -55.454 * A*B + 142.39 * A*C + -49.8679 * A*D + ...
    0.882743 * A*E + -2.13948 * A*F + -201.858 * B*C + 4.75252 * B*D + -3.24667 * B*E + ... 
    3.28482 * B*F + 5.73947 * C*D + 26.8874 * C*E + 8.62041 * C*F + 1.86596 * D*E + ...
    1.83478 * D*F + -0.0199006 * E*F + 317.531 * A^2 + 20.9851 * B^2 + -947.557 * C^2 + ...
    41.1666 * D^2 + -0.118071 * E^2 + 0.0549133 * F^2 + 93.6178 * A*B*C + 13.4298 * A*B*D + ... 
    -0.215279 * A*B*E + 0.209757 * A*B*F + 2.91659 * A*C*D + -0.409927 * A*C*E + -0.565571 * A*C*F + ... 
    -0.014349 * A*D*E + 0.100685 * A*D*F + 0.000378103 * A*E*F + -36.3298 * B*C*D + -0.802331 * B*C*E + ...
    -2.29175 * B*C*F + -0.056867 * B*D*E + -0.487262 * B*D*F + 0.0198284 * B*E*F + -1.22076 * C*D*E + ...
    -0.321103 * C*D*F + -0.041982 * C*E*F + -0.00774027 * D*E*F + -8.14374 * A^2*B + -65.0449 * A^2*C + ...
    9.5668 * A^2*D + -0.511996 * A^2*E + -0.0532939 * A^2*F + 11.1409 * A*B^2 + 117.915 * A*C^2 + ...
    -3.42037 * A*D^2 + 0.0107976 * A*E^2 + 0.00670067 * A*F^2 + 247.927 * B^2*C + 6.57367 * B^2*D + ...
    -0.237409 * B^2*E + -0.505571 * B^2*F + 720.059 * B*C^2 + 33.1914 * B*D^2 + 0.0413402 * B*E^2 + ...
    -0.00861452 * B*F^2 + 64.5214 * C^2*D + -80.9233 * C^2*E + 2.35222 * C^2*F + 15.2189 * C*D^2 +...
    0.179359 * C*E^2 + -0.0219809 * C*F^2 + -0.338999 * D^2*E + -0.460574 * D^2*F + ... 
    0.0154954 * D*E^2 + -0.00312015 * D*F^2 + 0.000173958 * E^2*F + 6.12009e-05 * E*F^2 + ...
    -96.7571 * A^3 + -6.90393 * B^3 + 1611.97 * C^3 + 13.3949 * D^3 + 0.000553098 * E^3 + ...
    -0.000103342 * F^3 + -2.86345 * A*B*C*D + 0.292035 * A*B*C*E + -0.145449 * A*B*C*F + 0.00842629 * A*B*D*E + ...
    -0.0383209 * A*B*D*F + -0.000229872 * A*B*E*F + 0.0299418 * A*C*D*E + 0.0130594 * A*C*D*F + 0.00124285 * A*C*E*F + ...
    -6.53392e-05 * A*D*E*F + -0.442602 * B*C*D*E + 0.108488 * B*C*D*F + -4.60445e-05 * B*C*E*F + -0.000384892 * B*D*E*F + ...
    0.00240434 * C*D*E*F + -2.55323 * A^2*B^2 + -4.73381 * A^2*B*C + -2.34505 * A^2*B*D + 0.0696367 * A^2*B*E + ...
    0.0194517 * A^2*B*F + -19.087 * A^2*C^2 + 2.68776 * A^2*C*D + -0.175942 * A^2*C*E + 0.068596 * A^2*C*F + ...
    -0.373283 * A^2*D^2 + -0.018605 * A^2*D*E + -0.0180184 * A^2*D*F + -0.0001931 * A^2*E^2 + ...
    -0.0001753 * A^2*E*F + -7.44708e-05 * A^2*F^2 + 0.420279 * A*B^2*C + 1.3214 * A*B^2*D + ...
    -0.0308366 * A*B^2*E + -0.0689113 * A*B^2*F + -45.015 * A*B*C^2 + 2.44579 * A*B*D^2 + ...
    -0.00127527 * A*B*E^2 + -6.06842e-05 * A*B*F^2 + -1.95682 * A*C^2*D + 0.164641 * A*C^2*E + ...
    -0.317879 * A*C^2*F + -4.57419 * A*C*D^2 + 0.00925488 * A*C*E^2 + 0.000925259 * A*C*F^2 + 0.025754 * A*D^2*E + ...
    0.00950929 * A*D^2*F + -7.62809e-05 * A*D*E^2 + 5.68772e-05 * A*D*F^2 + -1.45491e-05 * A*E^2*F + ...
    7.79113e-07 * A*E*F^2 + -280.042 * B^2*C^2 + 3.65076 * B^2*C*D + 0.430375 * B^2*C*E + ...
    0.428879 * B^2*C*F + -4.8309 * B^2*D^2 + 0.0284225 * B^2*D*E + 0.0314442 * B^2*D*F + ...
    0.00417335 * B^2*E^2 + -0.00287522 * B^2*E*F + -0.000248101 * B^2*F^2 + 24.3206 * B*C^2*D + ...
    1.33804 * B*C^2*E + 0.207228 * B*C^2*F + -3.72626 * B*C*D^2 + -0.0180914 * B*C*E^2 + ...
    0.00199625 * B*C*F^2 + 0.00430464 * B*D^2*E + 0.0181553 * B*D^2*F + 0.00441528 * B*D*E^2 +  ...
    0.000683806 * B*D*F^2 + 2.29873e-05 * B*E^2*F + -2.0355e-05 * B*E*F^2 + -14.2317 * C^2*D^2 + ...
    0.180011 * C^2*D*E + -0.422234 * C^2*D*F + 0.0439532 * C^2*E^2 + 0.0766046 * C^2*E*F + -0.00124581 * C^2*F^2 + ...
    0.218019 * C*D^2*E + -0.0750498 * C*D^2*F + 0.000718444 * C*D*E^2 + 0.000967824 * C*D*F^2 + -0.000234852 * C*E^2*F + ...
    5.06746e-08 * C*E*F^2 + -0.00309145 * D^2*E^2 + 0.00120206 * D^2*E*F + 5.07309e-05 * D^2*F^2 + ...
    1.66461e-05 * D*E^2*F + 4.57299e-06 * D*E*F^2 + -1.63292e-08 * E^2*F^2 + 1.90393 * A^3*B + ...
    6.68537 * A^3*C + 0.204008 * A^3*D + 0.089302 * A^3*E + 0.010571 * A^3*F + 5.86416 * A*B^3 + ...
    237.922 * A*C^3 + -0.652979 * A*D^3 + -8.54246e-05 * A*E^3 + -6.82095e-06 * A*F^3 + ...
    -66.6243 * B^3*C + -0.94026 * B^3*D + 0.25227 * B^3*E + 0.177121 * B^3*F + -15.8932 * B*C^3 +...
    -6.78254 * B*D^3 + -0.000971038 * B*E^3 + 9.25175e-06 * B*F^3 + 156.465 * C^3*D + 63.8234 * C^3*E + ...
    -1.98335 * C^3*F + 6.27085 * C*D^3 + -0.00166369 * C*E^3 + 1.7882e-05 * C*F^3 + -0.0153412 * D^3*E + ...
    0.0794819 * D^3*F + -0.000277803 * D*E^3 + 2.00476e-06 * D*F^3 + -2.0509e-06 * E^3*F + ...
    -5.24406e-08 * E*F^3 + 9.87042 * A^4 + -5.94802 * B^4 + -2901.29 * C^4 + -5.0876 * D^4 + ...
    2.74637e-05 * E^4 + 7.09687e-08 * F^4;


        end
        function OBJECT = FitnessFunction(~,GA_Ann_MODEL,Ar,Ind)
   
            ANN_OUT=sim(GA_Ann_MODEL,Ind');
    
                OBJECT=abs(Ar-ANN_OUT);
                OBJECT=1/(1+OBJECT);

        end
        function Childs=MakeChild(~,Parents)

            PaSize=size(Parents,1);
            Childs=[];
            for i=1:2:PaSize
              alpha=rand;
              Childs=[Childs ; Parents(i,:)*alpha+Parents(i+1,:)*(1-alpha)];
              Childs=[Childs ; Parents(i,:)*(1-alpha)+Parents(i+1,:)*alpha];
            end
        end
        function ParentsID = ParentSelection(~,Fitness,PaSize,K)
        for i=1:PaSize
          CandidateID=randi(length(Fitness),1,K);
          CandidateFitness=Fitness(CandidateID);
          MaxInCF=find(max(CandidateFitness)==CandidateFitness);
          ParentsID(i)=CandidateID(MaxInCF(1));
        end

    end
        function MatingPool=Mutation(app,MatingPool,MaxIter,Iter,Max,Min)

            MateSize=size(MatingPool,1);
            for i=1:MateSize
                A=(Max-Min)/2;
                B=(Max-Min)/150;
                m=(B-A)/(MaxIter-1);
                Sigma=m*(Iter-1)+A;
             MatingPool(i,:)= MatingPool(i,:)-normrnd(MatingPool(i,:),Sigma);
             MatingPool(i,:)=min(MatingPool(i,:),Max);
             MatingPool(i,:)=max(MatingPool(i,:),Min);
            end

      end
        function IN_ANN_MODEL = ANN_regression(app,CTrainData,CTrainTarget,SI,Goal,Reg,Epoch)
%                    si=[7 10 15 8];  
       TF1 = 'tansig';   % {tansig, logsig, purelin} ==> Transfer Function for first hidden layer
       TF2 = 'logsig';   % {tansig, logsig, purelin} ==> Transfer Function for second hidden layer
       TF3 = 'purelin';   % {tansig, logsig, purelin} ==> Transfer Function for second hidden layer
       BTF = 'trainlm';  % train+{bfg, br, cgb, cgf, cgp, gd, gdm, gda, gdx, lm, oss, r, rp, scg}, ==> Train Function
       PF = 'msereg';    % Performance Metric for MLP
      net = newff(CTrainData',CTrainTarget',SI,{TF1 TF3 TF2},BTF,PF);
      net.trainparam.epochs =Epoch;
      net.trainparam.goal = Goal;
      net.trainparam.showwindow =false  ;
      net.trainparam.show =inf ;

        net.divideparam.trainRatio = 50;
        net.divideparam.valRatio = 25;
        net.divideparam.testRatio = 25;
        
        net.performParam.regularization=Reg;
      
      net.performFcn=PF;
      net=init(net); %initialising the weight and bias wectors
      net=train(net,CTrainData',CTrainTarget'); %training the network
      IN_ANN_MODEL=net;
      
        end
        function R2 = rsquare(~,X,Y)

                    
                    % Linear regression according to the model: a + bx
                    A = [ones(length(X),1) X];
                    b = pinv(A'*A)*A'*Y;
                    Y_hat = A*b;
                    
                    % Calculation of R2 according to the formula: SSreg/SStot
                    SSreg = sum((Y_hat - mean(Y)).^2);
                    SStot = sum((Y - mean(Y)).^2);
                    R2 = SSreg/SStot;
                    
                    % Output limitations
                    if R2 > 1, error('Irregular value, check your data');end
                    if R2 < 0, error('Irregular value, check your data');end
                                
        end
        function Value = MY_Get_Absent_Value(app)
%                  PlotCollection={'Location','Depth','Width','Length','Eps','Vs'};
                   Val={};
                   if ~ismember(app.FirstParDropDown.Value,Val)
                       Val=[Val app.FirstParDropDown.Value];
                   end
                   if ~ismember(app.SecParDropDown.Value,Val)
                       Val=[Val app.SecParDropDown.Value];
                   end
                   if ~ismember(app.ThirdParDropDown.Value,Val)
                       Val=[Val app.ThirdParDropDown.Value];
                   end
                   if ~ismember(app.FourthParDropDown.Value,Val)
                       Val=[Val app.FourthParDropDown.Value];
                   end
                   if ~ismember(app.Change1DropDown.Value,Val)
                       Val=[Val app.Change1DropDown.Value];
                   end
                   if ~ismember(app.Change2DropDown.Value,Val)
                       Val=[Val app.Change2DropDown.Value];
                   end

                   In=ismember(app.PlotCollection,Val);

                   Loc=find(0==In);
                   Value=app.PlotCollection{Loc};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           load Data.mat
           load('Ann_Model.mat')
           app.AllData=Data.*[4 4 4 4 1 1 1];
           app.Ann_MODEL=Ann_MODEL;
        end

        % Button pushed function: PlotSectionButton
        function PlotSectionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PlotTab;
        end

        % Button pushed function: PredictionSectionButton
        function PredictionSectionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.PredictionTab;
        end

        % Button pushed function: OptimizationSectionButton
        function OptimizationSectionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.OptimizationTab;
        end

        % Button pushed function: NeuralNetworkSectionButton
        function NeuralNetworkSectionButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.NeuralNetworkTab;
        end

        % Button pushed function: PlottingExplanation
        function PlottingExplanationButtonPushed(app, event)
            Name='Plot Section:';
            String=['The result of a parametric study for measuring the efficiency of the rectangular geofoam-filled trench in non-homogeneous soil is presented as a diagram. These results are available for other researchers to validate their model. An automated model is developed by coupling Python-Plaxis programs for changing six different parameters, which is result of collecting 20,000 data.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PlottingExplanation_2
        function PlottingExplanation_2ButtonPushed(app, event)
            Name='Variable Parameters:';
            String=['The Y-axis of the diagram is always the efficiency of the geofoam-filled trench. The main variable is (X) axis and it can be illustrated as a function of the second variable. For instance, by selecting depth as a main variable and shear wave velocity of the soil (Vs) as a second variable, the diagram will be illustrated based on Depth-Efficiency for different values of Vs.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);  
        end

        % Button pushed function: PlottingExplanation_3
        function PlottingExplanation_3ButtonPushed(app, event)
            Name='Fix Parameters Help:';
            String=['These parameters are fixed and will not change during presenting the results through digram. The value of these parameters will be written at the top of the diagram, too.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PlottingExplanation_4
        function PlottingExplanation_4ButtonPushed(app, event)
            Name='Result Plot Help:';
            String=['The diagram shows the efficiency of the Geofoam-Filled trench, which is (Y) axis and the main variable, which is (X) axis and can be selected by the user. The main variable will be drawn for different values of the second variable that can be selected by the user, too.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Value changed function: Change1DropDown
        function Change1DropDownValueChanged(app, event)
            value = app.Change1DropDown.Value;
            
            app.Change1DropDown.Enable=0;

            app.Selected(5)=1;

           Val=1;
           if strcmp(value,app.FirstParDropDown.Value)
               app.FirstParDropDown.Items= app.PlotCollection;
               app.FirstParDropDown.Value= MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown,app.FirstParDropDown.Value);
           end
           
          if strcmp(value,app.SecParDropDown.Value)
               app.SecParDropDown.Items= app.PlotCollection;
               app.SecParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_2,app.SecParDropDown.Value);
          end
          
           if strcmp(value,app.FourthParDropDown.Value)
               app.FourthParDropDown.Items= app.PlotCollection;
               app.FourthParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_4,app.FourthParDropDown.Value);
           end
           
           if strcmp(value,app.ThirdParDropDown.Value)
              app.ThirdParDropDown.Items= app.PlotCollection;
              app.ThirdParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_3,app.ThirdParDropDown.Value);
           end
           if strcmp(value,app.Change2DropDown.Value)
               app.Change2DropDown.Items= app.PlotCollection;
               app.Change2DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Value changed function: Change2DropDown
        function Change2DropDownValueChanged(app, event)
            value = app.Change2DropDown.Value;
            app.Change2DropDown.Enable=0;    
            app.Selected(6)=1;
           
%            [~,Loc]=ismember( value,app.PlotCollection);
%            app.PlotCollection(Loc)=[];
           Val=1;
           if strcmp(value,app.FirstParDropDown.Value)
               app.FirstParDropDown.Items= app.PlotCollection;
               app.FirstParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown,app.FirstParDropDown.Value);
           end
           
          if strcmp(value,app.SecParDropDown.Value)
               app.SecParDropDown.Items= app.PlotCollection;
               app.SecParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_2,app.SecParDropDown.Value);
          end
          
           if strcmp(value,app.FourthParDropDown.Value)
               app.FourthParDropDown.Items= app.PlotCollection;
               app.FourthParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_4,app.FourthParDropDown.Value);
           end
           
           if strcmp(value,app.ThirdParDropDown.Value)
              app.ThirdParDropDown.Items= app.PlotCollection;
              app.ThirdParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_3,app.ThirdParDropDown.Value);
           end
           if strcmp(value,app.Change1DropDown.Value)
               app.Change1DropDown.Items= app.PlotCollection;
               app.Change1DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Value changed function: FirstParDropDown
        function FirstParDropDownValueChanged(app, event)
            value = app.FirstParDropDown.Value;
            My_SetValue_Param(app,app.DropDown,value);
            app.FirstParDropDown.Enable=0;
            app.Selected(1)=1;
           
%            [~,Loc]=ismember( value,app.PlotCollection);
%            app.PlotCollection(Loc)=[];
           Val=1;
           if strcmp(value,app.SecParDropDown.Value)
               app.SecParDropDown.Items= app.PlotCollection;
               app.SecParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_2,app.SecParDropDown.Value);
           end
           
          if strcmp(value,app.ThirdParDropDown.Value)
               app.ThirdParDropDown.Items= app.PlotCollection;
               app.ThirdParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_3,app.ThirdParDropDown.Value);
          end
          
           if strcmp(value,app.FourthParDropDown.Value)
               app.FourthParDropDown.Items= app.PlotCollection;
               app.FourthParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_4,app.FourthParDropDown.Value);
           end
           
           if strcmp(value,app.Change1DropDown.Value)
               app.Change1DropDown.Items= app.PlotCollection;
               app.Change1DropDown.Value=MY_Get_Absent_Value(app);
                Val=Val+1;
           end
           if strcmp(value,app.Change2DropDown.Value)
               app.Change2DropDown.Items= app.PlotCollection;
               app.Change2DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Value changed function: SecParDropDown
        function SecParDropDownValueChanged(app, event)
            value = app.SecParDropDown.Value;
            My_SetValue_Param(app,app.DropDown_2,value)
            app.SecParDropDown.Enable=0;
           app.Selected(2)=1;
           
%            [In,Loc]=ismember( value,app.PlotCollection);
%            app.PlotCollection(Loc)=[];
           Val=1;
           if strcmp(value,app.FirstParDropDown.Value)
               app.FirstParDropDown.Items= app.PlotCollection;
               app.FirstParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown,app.FirstParDropDown.Value);
           end
           
          if strcmp(value,app.ThirdParDropDown.Value)
               app.ThirdParDropDown.Items= app.PlotCollection;
               app.ThirdParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_3,app.ThirdParDropDown.Value);
          end
          
           if strcmp(value,app.FourthParDropDown.Value)
               app.FourthParDropDown.Items= app.PlotCollection;
               app.FourthParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_4,app.FourthParDropDown.Value);
           end
           
           if strcmp(value,app.Change1DropDown.Value)
               app.Change1DropDown.Items= app.PlotCollection;
               app.Change1DropDown.Value=MY_Get_Absent_Value(app);
                Val=Val+1;
           end
           if strcmp(value,app.Change2DropDown.Value)
               app.Change2DropDown.Items= app.PlotCollection;
               app.Change2DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Value changed function: ThirdParDropDown
        function ThirdParDropDownValueChanged(app, event)
             value = app.ThirdParDropDown.Value;
             My_SetValue_Param(app,app.DropDown_3,value)
            app.ThirdParDropDown.Enable=0;
            app.Selected(3)=1;
           
%            [In,Loc]=ismember( value,app.PlotCollection);
%            app.PlotCollection(Loc)=[];
           Val=1;
           if strcmp(value,app.FirstParDropDown.Value)
               app.FirstParDropDown.Items= app.PlotCollection;
               app.FirstParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown,app.FirstParDropDown.Value);
           end
           
          if strcmp(value,app.SecParDropDown.Value)
               app.SecParDropDown.Items= app.PlotCollection;
               app.SecParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_2,app.SecParDropDown.Value);
          end
          
           if strcmp(value,app.FourthParDropDown.Value)
               app.FourthParDropDown.Items= app.PlotCollection;
               app.FourthParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_4,app.FourthParDropDown.Value);
           end
           
           if strcmp(value,app.Change1DropDown.Value)
               app.Change1DropDown.Items= app.PlotCollection;
               app.Change1DropDown.Value=MY_Get_Absent_Value(app);
                Val=Val+1;
           end
           if strcmp(value,app.Change2DropDown.Value)
               app.Change2DropDown.Items= app.PlotCollection;
               app.Change2DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Value changed function: FourthParDropDown
        function FourthParDropDownValueChanged(app, event)
            value = app.FourthParDropDown.Value;
             
            My_SetValue_Param(app,app.DropDown_4,value)
            app.FourthParDropDown.Enable=0;
            app.Selected(4)=1;
           
%            [~,Loc]=ismember( value,app.PlotCollection);
%            app.PlotCollection(Loc)=[];
           Val=1;
           if strcmp(value,app.FirstParDropDown.Value)
               app.FirstParDropDown.Items= app.PlotCollection;
               app.FirstParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown,app.FirstParDropDown.Value);
           end
           
          if strcmp(value,app.SecParDropDown.Value)
               app.SecParDropDown.Items= app.PlotCollection;
               app.SecParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_2,app.SecParDropDown.Value);
          end
          
           if strcmp(value,app.ThirdParDropDown.Value)
               app.ThirdParDropDown.Items= app.PlotCollection;
               app.ThirdParDropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
               My_SetValue_Param(app,app.DropDown_3,app.ThirdParDropDown.Value);
           end
           
           if strcmp(value,app.Change1DropDown.Value)
               app.Change1DropDown.Items= app.PlotCollection;
               app.Change1DropDown.Value=MY_Get_Absent_Value(app);
                Val=Val+1;
           end
           if strcmp(value,app.Change2DropDown.Value)
               app.Change2DropDown.Items= app.PlotCollection;
               app.Change2DropDown.Value=MY_Get_Absent_Value(app);
               Val=Val+1;
           end
           Automatic_Plot(app);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
             Automatic_Plot(app);
        end

        % Button pushed function: BackHomeButton
        function BackHomeButtonPushed(app, event)
             app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: PredictionExplanation
        function PredictionExplanationButtonPushed(app, event)
             Name='Prediction Help:';
            String=['The efficiency of the rectangular geofoam-filled trench in non-homogeneous soil can be predicted through the developed ANN model and a quartic polynomial equation. This section helps the user to predict the efficiency of the trench in any specific soil and trench with different dimensions.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Value changed function: DataSourceDropDown
        function DataSourceDropDownValueChanged(app, event)
            value = app.DataSourceDropDown.Value;
            if strcmp(value,'Select Data Source')
                app.WEditField.Enable='off';
                app.LengthEditField.Enable='off';
                app.EPSEditField.Enable='off';
                app.VSEditField.Enable='off';
                app.XlocEditField.Enable='off';
                app.DepthEditField.Enable='off';
                app.UploadDataButton.Enable='off';
                app.PredictButton.Enable='off';
                app.ANNOutputEditField.Enable='off';
                app.EquationOutputEditField.Enable='off';
            elseif strcmp(value,'Custom Data') 
                app.WEditField.Enable='on';
                app.LengthEditField.Enable='on';
                app.EPSEditField.Enable='on';
                app.VSEditField.Enable='on';
                app.XlocEditField.Enable='on';
                app.DepthEditField.Enable='on';
                app.ANNOutputEditField.Enable='off';
                app.EquationOutputEditField.Enable='off';
                app.UploadDataButton.Enable='off';
                app.PredictButton.Enable='on';
            else 
                app.WEditField.Enable='off';
                app.LengthEditField.Enable='off';
                app.EPSEditField.Enable='off';
                app.VSEditField.Enable='off';
                app.XlocEditField.Enable='off';
                app.DepthEditField.Enable='off';
                app.ANNOutputEditField.Enable='off';
                app.EquationOutputEditField.Enable='off';
                app.UploadDataButton.Enable='on';
                app.PredictButton.Enable='off';        
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
%             value = app.DropDown.Value;
            Automatic_Plot(app);
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
%             value = app.DropDown_2.Value;
            Automatic_Plot(app);
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
%             value = app.DropDown_3.Value;
            Automatic_Plot(app);
        end

        % Value changed function: DropDown_4
        function DropDown_4ValueChanged(app, event)
%             value = app.DropDown_4.Value;
            Automatic_Plot(app);
        end

        % Button pushed function: CostumDataPrediction
        function CostumDataPredictionButtonPushed(app, event)
            Name='Costum Data Help:';
            String=['The method for using the artificial neural model for predicting the efficiency of the geofoam-filled trench is including in writing different parameters as costume data or uploading an Excel file. The value of the predicted efficiency for costume data will be visible in ANN and Equation output.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_8
        function PredictionExplanation_8ButtonPushed(app, event)
            Name='Upload File Help:';
            String=['An excel file can be uploaded as an external file. The excel file should be including six columns, which are the input parameters. The input parameters in the Excel file should be the same order as costume data. The result of calculated efficiency by ANN and Equation will be saved as a new excel file, which is called "Result" in the same folder that the user used for uploading the data.'];
             uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_2
        function PredictionExplanation_2ButtonPushed(app, event)
             Name='Location of Trench:';
            String=['This value is the location of the trench, which is the distance of the geofoam-filled trench from the vibration source. In this parametric study, the values of the location are between 3 and 15 m.'];
           uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_3
        function PredictionExplanation_3ButtonPushed(app, event)
            Name='Depth of trench:';
            String=['This value is the depth of the trench. In this parametric study, the values of the depth are between 2 and 7 m.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_4
        function PredictionExplanation_4ButtonPushed(app, event)
             Name='Width of trench:';
            String=['This value is the width of the trench. In this parametric study, the values of the width are between 0.3 and 1.5 m.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_5
        function PredictionExplanation_5ButtonPushed(app, event)
             Name='First Layer Thickness:';
            String=['For considering the non-homogeneity of the soil, two layers are considered and the thickness of the first layer is changeable for evaluating the effect of the thickness of the first layer on the efficiency of the trench. The range of the thickness of the first layer is between 2 and 10 m.'];
           uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_6
        function PredictionExplanation_6ButtonPushed(app, event)
            Name='Densiy of EPS:';
            String=['In this project, the geofoam material is selected for filling the trench. EPS is the density of the geofoam, which is the most important feature that influences the efficiency of the trench. The value of the density of the EPS is changeable from 12 to 29 Kg/m3 (ASTM D6817).'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_7
        function PredictionExplanation_7ButtonPushed(app, event)
             Name='Shar wave velocity:';
            String=['Another parameter, which is important in non-homogeneous soil is the shear wave velocity (Vs) of the first layer. For this purpose, Vs of the first layer changes in the range of 200-400 m/s. The shear wave velocity of the second layer is considered as 400 m/s. Therefore, with the value of Vs=400, the soil is considered as homogenous soil.'];
           uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_9
        function PredictionExplanation_9ButtonPushed(app, event)
            Name='ANN Model Help:';
            String=['A multilayered feedforward neural network model is developed for predicting the efficiency of the geofoam-filled trench based on the 20,000 data in the Plotting section. The ANN is including input parameters (costume data), output (efficiency), and two hidden layers with 10 and 15 neurons. The first run of the prediction may take some seconds. Since the ANN model should be trained.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictionExplanation_10
        function PredictionExplanation_10ButtonPushed(app, event)
            Name='Equation Model:';
            String=['A quartic polynomial equation is developed based on Surface Response Methodology, which is a statistical method for predicting the efficiency of the trench based on the 20,000 data in the plotting section. The predicted value of the efficiency from equation and ANN can be compared with the actual value for selecting the best efficiency.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            app.PredictButton.Enable='off';
            Data_source_Value=app.DataSourceDropDown.Value;
            if strcmp(Data_source_Value,'Custom Data') 
                app.ANNOutputEditField.Enable='on';
                app.EquationOutputEditField.Enable='on';
               Xloc= app.XlocEditField.Value;
               Depth= app.DepthEditField.Value;
               W= app.WEditField.Value;
               Length= app.LengthEditField.Value;
               EPS= app.EPSEditField.Value;
               VS= app.VSEditField.Value;
               Data=[Xloc Depth W Length EPS VS];                
             elseif strcmp(Data_source_Value,'Read From File')
               Data=app.User_prediction_Data;
               Data=Data(:,1:6);
            end
            ANN_MODEL_PRE=app.Ann_MODEL;
            if isempty(ANN_MODEL_PRE)
                Name='Ann Error:';
                String1='ANN predictor Not find.';
                uialert(app.UIFigure,String1,Name,'Icon','info','Modal',true);              
            end
         
                 ANN_Out=sim(ANN_MODEL_PRE,Data');
                 Len=length(ANN_Out);
              if Len==1
                  app.ANNOutputEditField.Value=ANN_Out;
              else
                 Name={'Ann'};
                 Location=strcat('A2:A',num2str(Len+1));
                 File_Path=app.User_Prediction_Path;
                 File_Name=strcat(File_Path,'Result.xlsx');
                 xlswrite(File_Name,Name,1,'A1:A1');
                 xlswrite(File_Name,ANN_Out',1,Location);
              end
                 for I=1:size(Data,1)   
                    Vector=Data(I,:)./[4 4 4 4 1 1];
                    EQU_Out(I)=Formola_Function(app,Vector);
                 end
                 
                 Len=length(EQU_Out);
                 if Len==1
                     app.EquationOutputEditField.Value=EQU_Out;
                 else
                     Name={'Equation'};
                     Location=strcat('B2:B',num2str(Len+1));
                      File_Path=app.User_Prediction_Path;
                      File_Name=strcat(File_Path,'Result.xlsx');
                     xlswrite(File_Name,Name,1,'B1:B1');
                     xlswrite(File_Name,EQU_Out',1,Location);
                 end
                 
           app.PredictButton.Enable='on';
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            FileName=strcat(path,file);
            app.User_prediction_Data=xlsread(FileName);
            app.User_Prediction_Path=path;
            app.PredictButton.Enable=1;
        end

        % Button pushed function: BackHomeButton_2
        function BackHomeButton_2Pushed(app, event)
          app.TabGroup.SelectedTab = app.HomeTab;  
        end

        % Button pushed function: OptimizationExplanation
        function OptimizationExplanationButtonPushed(app, event)
             Name='Genetic Algorithm:';
            String=['The optimization tool is useful for finding the best parameters for reaching a specific value of efficiency. A genetic algorithm code is implemented for mutual optimization of all input parameters. Genetic algorithms are commonly used to generate high-quality solutions to optimize and search problems by relying on biologically inspired operators such as mutation and crossover.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: OptimizationExplanation_2
        function OptimizationExplanation_2ButtonPushed(app, event)
            Name='Optimization Parameters:';
            String=['The Min and Max value of each parameter and the target value of the efficiency should be specified by the user. The developed GA will use the developed ANN model as a fitness function and will try to find the best value of all parameters for reaching the target value of the efficiency. The process of running the GA can be monitored through the curve.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: RunGeneticAlgorithmButton
        function RunGeneticAlgorithmButtonPushed(app, event)
            app.RunGeneticAlgorithmButton.Enable='off';
            CAllData = app.AllData;
            app.AllData=CAllData;
            GA_Ann_MODEL=app.Ann_MODEL;
            Ar=app.ArValueEditField.Value;
            
            PopSize=100;  % Population size
            MaxIter=50;  % Maximun Iteration
            Pm=0.2;
            Pc=0.9;
                    
            NuGen=size(CAllData(:,1:end-1),2) ;    % Number Of Gen

            %%
            Min_var=[];
             Min_var=[Min_var app.XlocEditField_2.Value];
             Min_var=[Min_var app.DepthEditField_2.Value];
             Min_var=[Min_var app.WEditField_2.Value];
             Min_var=[Min_var app.LengthEditField_2.Value];
             Min_var=[Min_var app.EPSEditField_2.Value];
             Min_var=[Min_var app.VSEditField_2.Value];

            Max_var=[];
             Max_var=[Max_var app.Xloc1EditField.Value];
             Max_var=[Max_var app.Depth1EditField.Value];
             Max_var=[Max_var app.W1EditField.Value];
             Max_var=[Max_var app.Length1EditField.Value];
             Max_var=[Max_var app.EPS1EditField.Value];
             Max_var=[Max_var app.VS1EditField.Value];
            %%
            PaSize=30;
            SurChS=20;
            Population=rand(PopSize,NuGen).*repmat((Max_var-Min_var),PopSize,1)+repmat(Min_var,PopSize,1);
            K=10;
            BestFitness=[];
            BestObject=[];
            Iter=1;
            hold(app.GA_CON_CURVE, 'off');
      while Iter<MaxIter
            fitness=[];
            for i=1:PopSize
            fitness=[fitness FitnessFunction(app,GA_Ann_MODEL,Ar,Population(i,:))];    
            end
            BestFitness=[BestFitness max(fitness)];
         %% Parent Selection (K-tournament)
         ParentID=ParentSelection(app,fitness,PaSize,K);
         Parents=Population(ParentID,:);  
         %% CrossOver
         MatingPool=Parents;
         Flag=0;
         if rand<Pc
             Childs=MakeChild(app,Parents);
             MatingPool=[MatingPool;Childs];
             Flag=1;
         end
         %% Mutation
         if rand<Pm
             MatingPool=Mutation(app,MatingPool,MaxIter,Iter,Max_var,Min_var);
              Flag=1;
         end
         %% SurviveSelection
         MateFitness=[];
         if Flag==1
            MateSize=size(MatingPool,1);
             for i=1:MateSize
                MateFitness=[MateFitness FitnessFunction(app,GA_Ann_MODEL,Ar,MatingPool(i,:))];    
             end
             
        [PSF,PSIndx]=sort(fitness);
        Population=Population(PSIndx,:);
        [~,MSIndx]=sort(MateFitness,'descend');
        MatingPool=MatingPool(MSIndx,:);
        Population(1:SurChS,:)=MatingPool(1:SurChS,:);    
          end
         %%
             plot(app.GA_CON_CURVE,BestFitness,'-b','Linewidth',2);
            hold(app.GA_CON_CURVE, 'on');
            pause(.05)
            xlabel(app.GA_CON_CURVE,sprintf('Iterations= %d',Iter))
            Out= Ar+((BestFitness(end)-1)/ BestFitness(end));
            title(app.GA_CON_CURVE,sprintf('ANN Output Value=%f ',Out));
          
         Iter=Iter+1;
        %  disp(Iter)
         
        end
         
            fitness=[];
            for i=1:PopSize
              fitness=[fitness FitnessFunction(app,GA_Ann_MODEL,Ar,Population(i,:))];    
            end
             [~,Ind]=max(fitness);
             BestAns=Population(Ind,:);
             ANN_OUT=sim(GA_Ann_MODEL,BestAns');


             app.Xloc_Out_EditField.Value=BestAns(1);
             app.Depth_Out_EditField.Value=BestAns(2);
             app.W_Out_EditField.Value=BestAns(3);
             app.Length_Out_EditField.Value=BestAns(4);
             app.EPS_Out_EditField.Value=BestAns(5);
             app.VS_Out_EditField.Value=BestAns(6);
             app.RunGeneticAlgorithmButton.Enable='on';

        end

        % Button pushed function: BackHomeButton_3
        function BackHomeButton_3Pushed(app, event)
             app.TabGroup.SelectedTab = app.HomeTab;
        end

        % Button pushed function: ANNmodelExplanation
        function ANNmodelExplanationButtonPushed(app, event)
            Name='ANN Help:';
            String=['An artificial neural network (ANN) is a non-linear statistical data model tool with layered structures similar to the structure of neurons in the brain that all nodes in layers are connected by weights. ANN can be trained over many examples and find the complex relationship and a pattern between inputs and outputs. These weights are automatically adjusted during training until the end.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: ANNmodelExplanation_2
        function ANNmodelExplanation_2ButtonPushed(app, event)
            Name='Hidden Layer Help:';
            String=['ANN combines several processing layers, including an input layer, which is different parameters of the trench or the soil (it is dependent on the parametric study of the user), one or more hidden layers, and an output layer, which is the efficiency of the trench. The number of the hidden layer is different and can be adjusted by the user based on the complicity of the problem.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: ANNmodelExplanation_3
        function ANNmodelExplanation_3ButtonPushed(app, event)
             Name='Neuron Number:';
            String=['In each hidden layer, there are several neurons and the user should select the number of neurons for each hidden layer. There is not any specific rule for selecting the neuron number. It should be adjusted for reaching the best performance.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: ANNmodelExplanation_4
        function ANNmodelExplanation_4ButtonPushed(app, event)
            Name='Upload Data Number:';
            String=['This ANN model is applicable for vibration isolation topic or any other parametric study in different situations. The new data should be prepared in an Excel file. The number of input parameters and their ranges are dependent on the parametric study of the user and can be including several parameters. The last column of the Excel file should be the efficiency of the trench.'];
             uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: ANNmodelExplanation_5
        function ANNmodelExplanation_5ButtonPushed(app, event)
            Name='ANN Result Number:';
            String=['The performance of the trained neural network is assessed by comparing the actual and predicted value. The resulting file of the train ANN model is saved in the same folder that the user selected the upload data. The results are presented in terms of different statistical coefficients for train and test data. The diagrams of the train and test data can be plotted by plot button.'];
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Button pushed function: R2HelpBTN
        function R2HelpBTNButtonPushed(app, event)
            Name='R^2 Help:';
            String='This box show the R^2 value for train set';
            uialert(app.UIFigure,String,Name,'Icon','info','Modal',true);
        end

        % Value changed function: NumberofHiddenLayersDropDown
        function NumberofHiddenLayersDropDownValueChanged(app, event)
            
            value = app.NumberofHiddenLayersDropDown.Value;
            NumberOfLayers=str2double(value);
                switch NumberOfLayers
                    case 1
                         app.thLayerNeronsEditField_2.Enable=0;
                         app.thLayerNeronsEditField_3.Enable=0;
                    case 2
                         app.thLayerNeronsEditField_2.Enable=1;
                         app.thLayerNeronsEditField_3.Enable=0;
                    case 3
                        app.thLayerNeronsEditField_2.Enable=1;
                        app.thLayerNeronsEditField_3.Enable=1;
                end
        end

        % Button pushed function: ANNUploadDataButton
        function ANNUploadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.xlsx');
            FileName=strcat(path,file);
            app.User_Ann_Data=xlsread(FileName);
            app.User_Ann_Path=path;
            app.TrainANNButton.Enable=1;
        end

        % Button pushed function: TrainANNButton
        function TrainANNButtonPushed(app, event)
             app.PlotTrainButton.Enable=0;
             app.PlotTestButton.Enable=0;
             app.Tr_PltDropDown.Enable=0;
             app.Te_pltDropDown.Enable=0;

           CAllData=app.User_Ann_Data;
           Error=0;

           TrPercent=0.7;

            if ~Error
                [Row, ~] = size(CAllData);
                RandInd=randperm(Row);
                CAllData=CAllData(RandInd,:);
                TrainInfo=CAllData(1:ceil(TrPercent*Row),:);
                TestInfo=CAllData(ceil(TrPercent*Row)+1:end,:);
                %% 
                CTrainTarget=TrainInfo(:,end); % Ar
                CTrainData=TrainInfo(:,1:end-1); % Parameters 
                CTestTarget=TestInfo(:,end);
                CTestData=TestInfo(:,1:end-1);
                %%
                app.TrainData=CTrainData;
                app.TrainTarget=CTrainTarget;
                app.TestData=CTestData;
                app.TestTarget=CTestTarget;
                %%
                SI=[];
                NumberOfLayers=str2double(app.NumberofHiddenLayersDropDown.Value);
                switch NumberOfLayers
                    case 1
                         SI=app.thLayerNeronsEditField.Value;
                    case 2
                        SI=[app.thLayerNeronsEditField.Value app.thLayerNeronsEditField_2.Value];
                    case 3
                        A=app.thLayerNeronsEditField.Value;
                        B= app.thLayerNeronsEditField_2.Value;
                        C= app.thLayerNeronsEditField_3.Value;
                        SI=[A B C];
%                     
                end
                %%
                
                Epoch=1000;
                Goal=0.001;
                Reg=0.01;
                [ Ann_MODEL_ANN ] = ANN_regression(app,CTrainData,CTrainTarget,SI,Goal,Reg,Epoch );
                File_Path=app.User_Ann_Path;
                Model=app.Ann_MODEL;
                 File_Name=strcat(File_Path,'ANN_Model.mat');
                 save(File_Name,'Model');
                TrPredicted=sim(Ann_MODEL_ANN,CTrainData'); %simulation the network
                TrResult.MSE=mse(TrPredicted'-CTrainTarget);
                TrResult.RMSE=sqrt(mse(TrPredicted'-CTrainTarget));
                TrResult.R2 = rsquare(app,TrPredicted',CTrainTarget);
                
                 Len=length(TrPredicted);
                 File_Path=app.User_Ann_Path;
                 File_Name=strcat(File_Path,'Ann_Result.xlsx');
                 Name={'Train Data real Value'};
                 Location=strcat('A2:A',num2str(Len+1));
                 xlswrite(File_Name,Name,1,'A1:A1');
                 xlswrite(File_Name,CTrainTarget,1,Location);
                 
                 Name={'Train Data Predicted Value'};
                 Location=strcat('B2:B',num2str(Len+1));
                 xlswrite(File_Name,Name,1,'B1:B1');
                 xlswrite(File_Name,TrPredicted',1,Location);
                 
                TePredicted=sim(Ann_MODEL_ANN,CTestData'); %simulation the network
                TeResult.MSE=mse(TePredicted'-CTestTarget);
                TeResult.RMSE=sqrt(mse(TePredicted'-CTestTarget));
                TeResult.R2 = rsquare(app,TePredicted',CTestTarget);
                
                app.TestPredicted=TePredicted';
                app.TrainPredicted=TrPredicted';
                app.TrainResult=TrResult;
                app.TestResult=TeResult;
                app.TrainMSEEditField.Value=TrResult.MSE;
                app.TrainRMSEEditField.Value=TrResult.RMSE;
                app.TrainR2EditField.Value=TrResult.R2;
                
                app.TestMSEEditField.Value=TeResult.MSE;
                app.TestRMSEEditField.Value=TeResult.RMSE;
                app.TestR2EditField.Value=TeResult.R2;
                
                
                Len=length(TePredicted);
                 File_Path=app.User_Ann_Path;
                 File_Name=strcat(File_Path,'Ann_Result.xlsx');
                 Name={'Test Data real Value'};
                 Location=strcat('D2:D',num2str(Len+1));
                 xlswrite(File_Name,Name,1,'D1:D1');
                 xlswrite(File_Name,CTestTarget,1,Location);
                 
                 Name={'Test Data Predicted Value'};
                 Location=strcat('E2:E',num2str(Len+1));
                 xlswrite(File_Name,Name,1,'E1:E1');
                 xlswrite(File_Name,TePredicted',1,Location);
                
                app.PlotTrainButton.Enable=1;
                app.PlotTestButton.Enable=1;
                app.Tr_PltDropDown.Enable=1;
                app.Te_pltDropDown.Enable=1;
               
            end
            
        end

        % Button pushed function: PlotTrainButton
        function PlotTrainButtonPushed(app, event)
            Type=app.Tr_PltDropDown.Value;
            targets=app.TrainTarget;
  
            outputs=app.TrainPredicted;
            errors=targets-outputs;
            MSE=mean(errors.^2);
            RMSE=sqrt(MSE);
         if strcmp(Type,'Out put')
                 hold(app.ANNRES, 'off');
                plot(app.ANNRES,targets,'k');
                hold(app.ANNRES, 'on');
                plot(app.ANNRES,outputs,'r');
                legend(app.ANNRES,'Target','Output');
                title(app.ANNRES,'Train Data');
                xlabel(app.ANNRES,'Sample Index');
                grid(app.ANNRES, 'on');
        elseif strcmp(Type,'Error')
                  hold(app.ANNRES, 'off');
                  plot(app.ANNRES,errors);
                  legend(app.ANNRES,'Error');
                  title(app.ANNRES,['MSE = ' num2str(MSE) ', RMSE = ' num2str(RMSE)]);
                  xlabel(app.ANNRES,'Sample Index');
                  title(app.ANNRES,'Train Data');
         end
        end

        % Button pushed function: PlotTestButton
        function PlotTestButtonPushed(app, event)
            Type=app.Te_pltDropDown.Value;
                
            targets=app.TestTarget;
  
            outputs=app.TestPredicted;
            errors=targets-outputs;
%             
            MSE=mean(errors.^2);
            RMSE=sqrt(MSE);
         if strcmp(Type,'Out put')
                 hold(app.ANNRES, 'off');
                plot(app.ANNRES,targets,'k');
                hold(app.ANNRES, 'on');
                plot(app.ANNRES,outputs,'r');
                legend(app.ANNRES,'Target','Output');
                title(app.ANNRES,'Test Data');
                xlabel(app.ANNRES,'Sample Index');
                grid(app.ANNRES, 'on');
        elseif strcmp(Type,'Error')
                  hold(app.ANNRES, 'off');
                  plot(app.ANNRES,errors);
                  legend(app.ANNRES,'Error');
                  title(app.ANNRES,['MSE = ' num2str(MSE) ', RMSE = ' num2str(RMSE)]);
                  xlabel(app.ANNRES,'Sample Index');
                  title(app.ANNRES,'Test Data');
 
        end
        end

        % Button pushed function: BackHomeButton_4
        function BackHomeButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.HomeTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GridLayout = uigridlayout(app.HomeTab);
            app.PlotSectionButton = uibutton(app.GridLayout, 'push');
            app.PlotSectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSectionButtonPushed, true);
            app.PlotSectionButton.Text = 'Plot Section';
            app.PredictionSectionButton = uibutton(app.GridLayout, 'push');
            app.PredictionSectionButton.ButtonPushedFcn = createCallbackFcn(app, @PredictionSectionButtonPushed, true);
            app.PredictionSectionButton.Text = 'Prediction Section';
            app.OptimizationSectionButton = uibutton(app.GridLayout, 'push');
            app.OptimizationSectionButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizationSectionButtonPushed, true);
            app.OptimizationSectionButton.Text = 'Optimization Section';
            app.NeuralNetworkSectionButton = uibutton(app.GridLayout, 'push');
            app.NeuralNetworkSectionButton.ButtonPushedFcn = createCallbackFcn(app, @NeuralNetworkSectionButtonPushed, true);
            app.NeuralNetworkSectionButton.Text = 'Neural Network Section';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'An App is designed and developed to predict the value of the efficiency and optimize different parameters for reaching the '; 'requested efficiency. The main aim of developing the App is to create the easy-usability of the developed model without requiring '; 'the advanced technical knowledge to use. The home page (first page) is including four buttons that users can click on each button '; 'and goes to the requested section ofthe App. The plot section presents just the result of the parametric study as diagram. The '; 'user can select the value for different parameters to see the results as a diagram. Prediction section can predict the value of the '; 'geofoam-filled trench in non-homogenous soil by the developed artificial neural network model (ANN) and quartic polynomial '; 'equation. The user can select any value for different parameters to predict the efficiency. The result of this section will be the '; 'predicted value of the efficiency of the trench based on ANN and the equation. The optimization section presents the developed '; 'couple of genetic algorithm-artificial neural network code for mutual optimization of all parameters. The user can change the '; 'range of all parameters and the efficiency of the trench. The result of this section will be the best parameters for reaching the '; 'specific value of the efficiency that the user selected. The Artificial Neural section is proper for developing a new artificial neural '; 'network model for new data. The user can upload the result of its parametric study that she/he did for a project, and select the '; 'number of hidden layers and click on train data. The results will be a new artificial neural network based on the user''s data that '; 'the user can use it for predicting new parameters of its parametric study result.  '; 'Author:'; 'Mehran Naghizadehrokni, Martin Ziegler'; ''};
            app.VibrationIsolationAppLabel = uilabel(app.GridLayout);
            app.VibrationIsolationAppLabel.Text = 'Vibration Isolation App';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'n6-1.png';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout2 = uigridlayout(app.PlotTab);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel = uilabel(app.GridLayout2);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel.Text = 'Select Parameters to Draw the Efficiency of the Trench';
            app.PlottingExplanation = uibutton(app.GridLayout2, 'push');
            app.PlottingExplanation.ButtonPushedFcn = createCallbackFcn(app, @PlottingExplanationButtonPushed, true);
            app.PlottingExplanation.Text = '?';
            app.SelectParameterstoDrawDiagramLabel = uilabel(app.GridLayout2);
            app.SelectParameterstoDrawDiagramLabel.Text = 'Select Parameters to Draw Diagram';
            app.PlottingExplanation_2 = uibutton(app.GridLayout2, 'push');
            app.PlottingExplanation_2.ButtonPushedFcn = createCallbackFcn(app, @PlottingExplanation_2ButtonPushed, true);
            app.PlottingExplanation_2.Text = '?';
            app.Change1DropDownLabel = uilabel(app.GridLayout2);
            app.Change1DropDownLabel.Text = 'Main Variable';
            app.Change1DropDown = uidropdown(app.GridLayout2);
            app.Change1DropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.Change1DropDown.ValueChangedFcn = createCallbackFcn(app, @Change1DropDownValueChanged, true);
            app.Change1DropDown.Value = 'Depth';
            app.Change2DropDownLabel = uilabel(app.GridLayout2);
            app.Change2DropDownLabel.Text = 'Second Variable';
            app.Change2DropDown = uidropdown(app.GridLayout2);
            app.Change2DropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.Change2DropDown.ValueChangedFcn = createCallbackFcn(app, @Change2DropDownValueChanged, true);
            app.Change2DropDown.Value = 'Vs';
            app.SelectValueforFixParametersLabel = uilabel(app.GridLayout2);
            app.SelectValueforFixParametersLabel.Text = 'Select Value for Fix Parameters';
            app.PlottingExplanation_3 = uibutton(app.GridLayout2, 'push');
            app.PlottingExplanation_3.ButtonPushedFcn = createCallbackFcn(app, @PlottingExplanation_3ButtonPushed, true);
            app.PlottingExplanation_3.Text = '?';
            app.UIAxes = uiaxes(app.GridLayout2);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PlottingExplanation_4 = uibutton(app.GridLayout2, 'push');
            app.PlottingExplanation_4.ButtonPushedFcn = createCallbackFcn(app, @PlottingExplanation_4ButtonPushed, true);
            app.PlottingExplanation_4.Text = '?';
            app.PlotButton = uibutton(app.GridLayout2, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = {'Plot'; ''};
            app.DropDown = uidropdown(app.GridLayout2);
            app.DropDown.Items = {'3', '6', '9', '12', '15'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '3';
            app.DropDown_2 = uidropdown(app.GridLayout2);
            app.DropDown_2.Items = {'0.3', '0.5', '0.7', '1', '1.5'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.Value = '0.3';
            app.DropDown_3 = uidropdown(app.GridLayout2);
            app.DropDown_3.Items = {'12', '15', '19', '22', '29'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '12';
            app.DropDown_4 = uidropdown(app.GridLayout2);
            app.DropDown_4.Items = {'2', '4', '6', '8', '10'};
            app.DropDown_4.ValueChangedFcn = createCallbackFcn(app, @DropDown_4ValueChanged, true);
            app.DropDown_4.Value = '2';
            app.FirstParDropDownLabel = uilabel(app.GridLayout2);
            app.FirstParDropDownLabel.Text = '1';
            app.FirstParDropDown = uidropdown(app.GridLayout2);
            app.FirstParDropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.FirstParDropDown.ValueChangedFcn = createCallbackFcn(app, @FirstParDropDownValueChanged, true);
            app.FirstParDropDown.Value = 'Location';
            app.SecParDropDownLabel = uilabel(app.GridLayout2);
            app.SecParDropDownLabel.Text = '2';
            app.SecParDropDown = uidropdown(app.GridLayout2);
            app.SecParDropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.SecParDropDown.ValueChangedFcn = createCallbackFcn(app, @SecParDropDownValueChanged, true);
            app.SecParDropDown.Value = 'Width';
            app.ThirdParDropDownLabel = uilabel(app.GridLayout2);
            app.ThirdParDropDownLabel.Text = '3';
            app.ThirdParDropDown = uidropdown(app.GridLayout2);
            app.ThirdParDropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.ThirdParDropDown.ValueChangedFcn = createCallbackFcn(app, @ThirdParDropDownValueChanged, true);
            app.ThirdParDropDown.Value = 'Eps';
            app.FourthParDropDownLabel = uilabel(app.GridLayout2);
            app.FourthParDropDownLabel.Text = '4';
            app.FourthParDropDown = uidropdown(app.GridLayout2);
            app.FourthParDropDown.Items = {'Location', 'Depth', 'Width', 'Length', 'Eps', 'Vs'};
            app.FourthParDropDown.ValueChangedFcn = createCallbackFcn(app, @FourthParDropDownValueChanged, true);
            app.FourthParDropDown.Value = 'Length';
            app.BackHomeButton = uibutton(app.GridLayout2, 'push');
            app.BackHomeButton.ButtonPushedFcn = createCallbackFcn(app, @BackHomeButtonPushed, true);
            app.BackHomeButton.Text = 'Back Home';
            app.MNaghizadehrokniMZieglerLabel = uilabel(app.GridLayout2);
            app.MNaghizadehrokniMZieglerLabel.Text = 'M. Naghizadehrokni, M. Ziegler';
            app.PredictionTab = uitab(app.TabGroup);
            app.PredictionTab.Title = 'Prediction';
            app.GridLayout3 = uigridlayout(app.PredictionTab);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_2 = uilabel(app.GridLayout3);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_2.Text = 'Predict the Efficiency of the Geofoam-Filled Trench';
            app.PredictionExplanation = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanationButtonPushed, true);
            app.PredictionExplanation.Text = '?';
            app.DataSourceDropDownLabel = uilabel(app.GridLayout3);
            app.DataSourceDropDownLabel.Text = 'Data Source';
            app.DataSourceDropDown = uidropdown(app.GridLayout3);
            app.DataSourceDropDown.Items = {'Select Data Source', 'Custom Data', 'Read From File'};
            app.DataSourceDropDown.ValueChangedFcn = createCallbackFcn(app, @DataSourceDropDownValueChanged, true);
            app.DataSourceDropDown.Value = 'Select Data Source';
            app.WEditField_2Label = uilabel(app.GridLayout3);
            app.WEditField_2Label.Text = 'Width(m)';
            app.WEditField = uieditfield(app.GridLayout3, 'numeric');
            app.WEditField.Limits = [0.3 1.5];
            app.WEditField.Enable = 'off';
            app.WEditField.Value = 0.3;
            app.LengthEditField_2Label = uilabel(app.GridLayout3);
            app.LengthEditField_2Label.Text = 'Length(m)';
            app.LengthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LengthEditField.Limits = [2 10];
            app.LengthEditField.Enable = 'off';
            app.LengthEditField.Value = 2;
            app.EPSEditField_2Label = uilabel(app.GridLayout3);
            app.EPSEditField_2Label.Text = 'EPS(Kg/m3)';
            app.EPSEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EPSEditField.Limits = [12 29];
            app.EPSEditField.Enable = 'off';
            app.EPSEditField.Value = 12;
            app.VSEditField_2Label = uilabel(app.GridLayout3);
            app.VSEditField_2Label.Text = 'Vs(m/s)';
            app.VSEditField = uieditfield(app.GridLayout3, 'numeric');
            app.VSEditField.Limits = [200 400];
            app.VSEditField.Enable = 'off';
            app.VSEditField.Value = 200;
            app.XlocEditField_2Label = uilabel(app.GridLayout3);
            app.XlocEditField_2Label.Text = 'Location(m)';
            app.XlocEditField = uieditfield(app.GridLayout3, 'numeric');
            app.XlocEditField.Limits = [3 15];
            app.XlocEditField.Enable = 'off';
            app.XlocEditField.Value = 3;
            app.DepthEditField_2Label = uilabel(app.GridLayout3);
            app.DepthEditField_2Label.Text = 'Depth(m)';
            app.DepthEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DepthEditField.Limits = [2 8];
            app.DepthEditField.Enable = 'off';
            app.DepthEditField.Value = 2;
            app.UploadDataButton = uibutton(app.GridLayout3, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Enable = 'off';
            app.UploadDataButton.Text = 'Upload Data';
            app.PredictionExplanation_2 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_2.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_2ButtonPushed, true);
            app.PredictionExplanation_2.Text = '?';
            app.PredictionExplanation_3 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_3.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_3ButtonPushed, true);
            app.PredictionExplanation_3.Text = '?';
            app.PredictionExplanation_4 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_4.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_4ButtonPushed, true);
            app.PredictionExplanation_4.Text = '?';
            app.PredictionExplanation_5 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_5.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_5ButtonPushed, true);
            app.PredictionExplanation_5.Text = '?';
            app.PredictionExplanation_6 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_6.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_6ButtonPushed, true);
            app.PredictionExplanation_6.Text = '?';
            app.PredictionExplanation_7 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_7.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_7ButtonPushed, true);
            app.PredictionExplanation_7.Text = '?';
            app.ReadFromFileLabel = uilabel(app.GridLayout3);
            app.ReadFromFileLabel.Text = 'Read From File';
            app.PredictionExplanation_8 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_8.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_8ButtonPushed, true);
            app.PredictionExplanation_8.Text = '?';
            app.CostumeDataLabel = uilabel(app.GridLayout3);
            app.CostumeDataLabel.Text = 'Costume Data';
            app.CostumDataPrediction = uibutton(app.GridLayout3, 'push');
            app.CostumDataPrediction.ButtonPushedFcn = createCallbackFcn(app, @CostumDataPredictionButtonPushed, true);
            app.CostumDataPrediction.Text = '?';
            app.Image_2 = uiimage(app.GridLayout3);
            app.PredictButton = uibutton(app.GridLayout3, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Enable = 'off';
            app.PredictButton.Text = 'Predict';
            app.PredictionExplanation_9 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_9.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_9ButtonPushed, true);
            app.PredictionExplanation_9.Text = '?';
            app.PredictionExplanation_10 = uibutton(app.GridLayout3, 'push');
            app.PredictionExplanation_10.ButtonPushedFcn = createCallbackFcn(app, @PredictionExplanation_10ButtonPushed, true);
            app.PredictionExplanation_10.Text = '?';
            app.ThefirstpredictionwilltakesometimeLabel = uilabel(app.GridLayout3);
            app.ThefirstpredictionwilltakesometimeLabel.Text = 'The first prediction will take some time';
            app.BackHomeButton_2 = uibutton(app.GridLayout3, 'push');
            app.BackHomeButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackHomeButton_2Pushed, true);
            app.BackHomeButton_2.Text = 'Back Home';
            app.MNaghizadehrokniMZieglerLabel_2 = uilabel(app.GridLayout3);
            app.MNaghizadehrokniMZieglerLabel_2.Text = 'M. Naghizadehrokni, M. Ziegler';
            app.ANNOutputEditFieldLabel = uilabel(app.GridLayout3);
            app.ANNOutputEditFieldLabel.Text = 'ANN Output (%)';
            app.ANNOutputEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ANNOutputEditField.Editable = 'off';
            app.ANNOutputEditField.Enable = 'off';
            app.EquationOutputEditFieldLabel = uilabel(app.GridLayout3);
            app.EquationOutputEditFieldLabel.Text = 'Equation Output (%)';
            app.EquationOutputEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EquationOutputEditField.Editable = 'off';
            app.EquationOutputEditField.Enable = 'off';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.GridLayout4 = uigridlayout(app.OptimizationTab);
            app.MinLabel = uilabel(app.GridLayout4);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.GridLayout4);
            app.MaxLabel.Text = 'Max';
            app.GAOutputLabel = uilabel(app.GridLayout4);
            app.GAOutputLabel.Text = 'GA. Out put';
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_3 = uilabel(app.GridLayout4);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_3.Text = 'Optimize the Parameters of the Geofoam-Filled Trench';
            app.OptimizationExplanation = uibutton(app.GridLayout4, 'push');
            app.OptimizationExplanation.ButtonPushedFcn = createCallbackFcn(app, @OptimizationExplanationButtonPushed, true);
            app.OptimizationExplanation.Text = '?';
            app.OptimizationExplanation_2 = uibutton(app.GridLayout4, 'push');
            app.OptimizationExplanation_2.ButtonPushedFcn = createCallbackFcn(app, @OptimizationExplanation_2ButtonPushed, true);
            app.OptimizationExplanation_2.Text = '?';
            app.GA_CON_CURVE = uiaxes(app.GridLayout4);
            title(app.GA_CON_CURVE, 'Convergence Curve')
            xlabel(app.GA_CON_CURVE, 'Iteration')
            ylabel(app.GA_CON_CURVE, 'Object Value')
            app.WEditFieldLabel = uilabel(app.GridLayout4);
            app.WEditFieldLabel.Text = 'Width (m)';
            app.WEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.WEditField_2.Limits = [0.3 1.5];
            app.WEditField_2.Value = 0.3;
            app.LengthEditFieldLabel = uilabel(app.GridLayout4);
            app.LengthEditFieldLabel.Text = 'Length (m)';
            app.LengthEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.LengthEditField_2.Limits = [2 10];
            app.LengthEditField_2.Value = 2;
            app.EPSEditFieldLabel = uilabel(app.GridLayout4);
            app.EPSEditFieldLabel.Text = 'EPS (Kg/m3)';
            app.EPSEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.EPSEditField_2.Limits = [12 29];
            app.EPSEditField_2.Value = 12;
            app.VSEditFieldLabel = uilabel(app.GridLayout4);
            app.VSEditFieldLabel.Text = 'Vs (m/s)';
            app.VSEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.VSEditField_2.Limits = [200 400];
            app.VSEditField_2.Value = 200;
            app.RunGeneticAlgorithmButton = uibutton(app.GridLayout4, 'push');
            app.RunGeneticAlgorithmButton.ButtonPushedFcn = createCallbackFcn(app, @RunGeneticAlgorithmButtonPushed, true);
            app.RunGeneticAlgorithmButton.Text = 'Run Genetic Algorithm';
            app.ArValueEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArValueEditField.Limits = [10 100];
            app.ArValueEditField.Value = 85;
            app.XlocEditFieldLabel = uilabel(app.GridLayout4);
            app.XlocEditFieldLabel.Text = 'Location (m)';
            app.XlocEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.XlocEditField_2.Limits = [3 15];
            app.XlocEditField_2.Value = 3;
            app.DepthEditFieldLabel = uilabel(app.GridLayout4);
            app.DepthEditFieldLabel.Text = 'Depth (m)';
            app.DepthEditField_2 = uieditfield(app.GridLayout4, 'numeric');
            app.DepthEditField_2.Limits = [2 8];
            app.DepthEditField_2.Value = 2;
            app.Xloc1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Xloc1EditField.Limits = [3 15];
            app.Xloc1EditField.Value = 15;
            app.Depth1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Depth1EditField.Limits = [2 8];
            app.Depth1EditField.Value = 7;
            app.W1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.W1EditField.Limits = [0.3 1.5];
            app.W1EditField.Value = 1.5;
            app.Length1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Length1EditField.Limits = [2 10];
            app.Length1EditField.Value = 10;
            app.EPS1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.EPS1EditField.Limits = [12 29];
            app.EPS1EditField.Value = 29;
            app.VS1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.VS1EditField.Limits = [200 400];
            app.VS1EditField.Value = 400;
            app.Xloc_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Xloc_Out_EditField.Editable = 'off';
            app.Depth_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Depth_Out_EditField.Editable = 'off';
            app.W_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.W_Out_EditField.Editable = 'off';
            app.Length_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Length_Out_EditField.Editable = 'off';
            app.EPS_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.EPS_Out_EditField.Editable = 'off';
            app.VS_Out_EditField = uieditfield(app.GridLayout4, 'numeric');
            app.VS_Out_EditField.Editable = 'off';
            app.ArValueEditFieldLabel = uilabel(app.GridLayout4);
            app.ArValueEditFieldLabel.Text = 'Target Efficiency (%)';
            app.BackHomeButton_3 = uibutton(app.GridLayout4, 'push');
            app.BackHomeButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackHomeButton_3Pushed, true);
            app.BackHomeButton_3.Text = 'Back Home';
            app.MNaghizadehrokniMZieglerLabel_3 = uilabel(app.GridLayout4);
            app.MNaghizadehrokniMZieglerLabel_3.Text = 'M. Naghizadehrokni, M. Ziegler';
            app.NeuralNetworkTab = uitab(app.TabGroup);
            app.NeuralNetworkTab.Title = 'Neural Network';
            app.GridLayout5 = uigridlayout(app.NeuralNetworkTab);
            app.TrainANNButton = uibutton(app.GridLayout5, 'push');
            app.TrainANNButton.ButtonPushedFcn = createCallbackFcn(app, @TrainANNButtonPushed, true);
            app.TrainANNButton.Enable = 'off';
            app.TrainANNButton.Text = 'Train ANN';
            app.ANNUploadDataButton = uibutton(app.GridLayout5, 'push');
            app.ANNUploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @ANNUploadDataButtonPushed, true);
            app.ANNUploadDataButton.Text = 'Upload Data';
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_4 = uilabel(app.GridLayout5);
            app.DrawDiagrambasedonEfficiencyoftheTrenchLabel_4.Text = 'Train a New Artificial Neural Network for New Data';
            app.ANNmodelExplanation = uibutton(app.GridLayout5, 'push');
            app.ANNmodelExplanation.ButtonPushedFcn = createCallbackFcn(app, @ANNmodelExplanationButtonPushed, true);
            app.ANNmodelExplanation.Text = '?';
            app.ANNmodelExplanation_2 = uibutton(app.GridLayout5, 'push');
            app.ANNmodelExplanation_2.ButtonPushedFcn = createCallbackFcn(app, @ANNmodelExplanation_2ButtonPushed, true);
            app.ANNmodelExplanation_2.Text = '?';
            app.ANNmodelExplanation_3 = uibutton(app.GridLayout5, 'push');
            app.ANNmodelExplanation_3.ButtonPushedFcn = createCallbackFcn(app, @ANNmodelExplanation_3ButtonPushed, true);
            app.ANNmodelExplanation_3.Text = '?';
            app.ANNmodelExplanation_4 = uibutton(app.GridLayout5, 'push');
            app.ANNmodelExplanation_4.ButtonPushedFcn = createCallbackFcn(app, @ANNmodelExplanation_4ButtonPushed, true);
            app.ANNmodelExplanation_4.Text = '?';
            app.ResultofTrainedANNModelLabel = uilabel(app.GridLayout5);
            app.ResultofTrainedANNModelLabel.Text = 'Result of Trained ANN Model';
            app.ANNmodelExplanation_5 = uibutton(app.GridLayout5, 'push');
            app.ANNmodelExplanation_5.ButtonPushedFcn = createCallbackFcn(app, @ANNmodelExplanation_5ButtonPushed, true);
            app.ANNmodelExplanation_5.Text = '?';
            app.ANNRES = uiaxes(app.GridLayout5);
            title(app.ANNRES, 'Title')
            xlabel(app.ANNRES, 'X')
            ylabel(app.ANNRES, 'Y')
            app.TrainMSEEditFieldLabel = uilabel(app.GridLayout5);
            app.TrainMSEEditFieldLabel.Text = 'Train MSE';
            app.TrainMSEEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TrainMSEEditField.Editable = 'off';
            app.TrainRMSEEditFieldLabel = uilabel(app.GridLayout5);
            app.TrainRMSEEditFieldLabel.Text = 'Train RMSE';
            app.TrainRMSEEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TrainRMSEEditField.Editable = 'off';
            app.TrainR2EditFieldLabel = uilabel(app.GridLayout5);
            app.TrainR2EditFieldLabel.Text = 'Train R2';
            app.TrainR2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.TrainR2EditField.Editable = 'off';
            app.TestMSEEditFieldLabel = uilabel(app.GridLayout5);
            app.TestMSEEditFieldLabel.Text = 'Test MSE';
            app.TestMSEEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TestMSEEditField.Editable = 'off';
            app.TestRMSELabel = uilabel(app.GridLayout5);
            app.TestRMSELabel.Text = 'Test RMSE';
            app.TestRMSEEditField = uieditfield(app.GridLayout5, 'numeric');
            app.TestRMSEEditField.Editable = 'off';
            app.TestR2EditFieldLabel = uilabel(app.GridLayout5);
            app.TestR2EditFieldLabel.Text = 'Test R2';
            app.TestR2EditField = uieditfield(app.GridLayout5, 'numeric');
            app.TestR2EditField.Editable = 'off';
            app.PlotTrainButton = uibutton(app.GridLayout5, 'push');
            app.PlotTrainButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTrainButtonPushed, true);
            app.PlotTrainButton.Enable = 'off';
            app.PlotTrainButton.Text = 'Plot Train';
            app.PlotTestButton = uibutton(app.GridLayout5, 'push');
            app.PlotTestButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTestButtonPushed, true);
            app.PlotTestButton.Enable = 'off';
            app.PlotTestButton.Text = 'Plot Test';
            app.Tr_PltDropDown = uidropdown(app.GridLayout5);
            app.Tr_PltDropDown.Items = {'Out put', 'Error'};
            app.Tr_PltDropDown.Enable = 'off';
            app.Tr_PltDropDown.Value = 'Out put';
            app.Te_pltDropDown = uidropdown(app.GridLayout5);
            app.Te_pltDropDown.Items = {'Out put', 'Error'};
            app.Te_pltDropDown.Enable = 'off';
            app.Te_pltDropDown.Value = 'Out put';
            app.R2HelpBTN = uibutton(app.GridLayout5, 'push');
            app.R2HelpBTN.ButtonPushedFcn = createCallbackFcn(app, @R2HelpBTNButtonPushed, true);
            app.R2HelpBTN.Text = '?';
            app.BackHomeButton_4 = uibutton(app.GridLayout5, 'push');
            app.BackHomeButton_4.ButtonPushedFcn = createCallbackFcn(app, @BackHomeButton_4Pushed, true);
            app.BackHomeButton_4.Text = 'Back Home';
            app.MNaghizadehrokniMZieglerLabel_4 = uilabel(app.GridLayout5);
            app.MNaghizadehrokniMZieglerLabel_4.Text = 'M. Naghizadehrokni, M. Ziegler';
            app.NumberofHiddenLayersDropDownLabel = uilabel(app.GridLayout5);
            app.NumberofHiddenLayersDropDownLabel.Text = 'Number of Hidden Layers';
            app.NumberofHiddenLayersDropDown = uidropdown(app.GridLayout5);
            app.NumberofHiddenLayersDropDown.Items = {'1', '2', '3'};
            app.NumberofHiddenLayersDropDown.ValueChangedFcn = createCallbackFcn(app, @NumberofHiddenLayersDropDownValueChanged, true);
            app.NumberofHiddenLayersDropDown.Value = '2';
            app.thLayerNeronsEditFieldLabel = uilabel(app.GridLayout5);
            app.thLayerNeronsEditFieldLabel.Text = '1''th Layer Nerons';
            app.thLayerNeronsEditField = uieditfield(app.GridLayout5, 'numeric');
            app.thLayerNeronsEditField.Limits = [2 25];
            app.thLayerNeronsEditField.Value = 15;
            app.thLayerNeronsEditField_2Label = uilabel(app.GridLayout5);
            app.thLayerNeronsEditField_2Label.Text = '2''th Layer Nerons';
            app.thLayerNeronsEditField_2 = uieditfield(app.GridLayout5, 'numeric');
            app.thLayerNeronsEditField_2.Limits = [2 25];
            app.thLayerNeronsEditField_2.Value = 12;
            app.thLayerNeronsEditField_3Label = uilabel(app.GridLayout5);
            app.thLayerNeronsEditField_3Label.Text = '3''th Layer Nerons';
            app.thLayerNeronsEditField_3 = uieditfield(app.GridLayout5, 'numeric');
            app.thLayerNeronsEditField_3.Limits = [2 25];
            app.thLayerNeronsEditField_3.Enable = 'off';
            app.thLayerNeronsEditField_3.Value = 8;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_mehran13990414

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end